classdef singleChannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Single                          matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportFilesMenu                 matlab.ui.container.Menu
        SaveProjectMenu                 matlab.ui.container.Menu
        OpenProjectMenu                 matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        DataFormatMenu                  matlab.ui.container.Menu
        DocumentsMenu                   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        LoginTab                        matlab.ui.container.Tab
        LoginPanel                      matlab.ui.container.Panel
        UsernameEditFieldLabel          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        LogInButton                     matlab.ui.control.Button
        DataTab                         matlab.ui.container.Tab
        LoadDataButton                  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        XAxisUnitButtonGroup            matlab.ui.container.ButtonGroup
        FramesButton                    matlab.ui.control.RadioButton
        TimeButton                      matlab.ui.control.RadioButton
        ExposureTimeEditFieldLabel      matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.NumericEditField
        YAxisUnitButtonGroup            matlab.ui.container.ButtonGroup
        FluorescenceIntensityButton     matlab.ui.control.RadioButton
        CurrentFlieEditFieldLabel       matlab.ui.control.Label
        CurrentFlieEditField            matlab.ui.control.EditField
        ChooseMLmodelButton             matlab.ui.control.Button
        ChoosePathButton                matlab.ui.control.Button
        MoleculeListListBox_2Label      matlab.ui.control.Label
        MoleculeListListBox_2           matlab.ui.control.ListBox
        HoldCtrlformultipleselectionLabel  matlab.ui.control.Label
        DeleteFileButton                matlab.ui.control.Button
        FilesListBoxLabel               matlab.ui.control.Label
        FilesListBox                    matlab.ui.control.ListBox
        DataReadingCheckPanel           matlab.ui.container.Panel
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        LoadDemoDataButton              matlab.ui.control.Button
        MLModelTab                      matlab.ui.container.Tab
        ModelsButtonGroup               matlab.ui.container.ButtonGroup
        PretrainedRNNButton             matlab.ui.control.RadioButton
        CustomRNNButton                 matlab.ui.control.RadioButton
        ApplymodeltodataButton          matlab.ui.control.Button
        CustomTrainingPanel             matlab.ui.container.Panel
        UIAxes9_2                       matlab.ui.control.UIAxes
        StartTrainingButton             matlab.ui.control.Button
        TrainingLampLabel               matlab.ui.control.Label
        TrainingLamp                    matlab.ui.control.Lamp
        StopTrainingButton              matlab.ui.control.Button
        ResetNetworkButton              matlab.ui.control.Button
        TrainSetTable                   matlab.ui.control.Table
        AllFilesCheckBox_2              matlab.ui.control.CheckBox
        ShowFilePathCheckBox_2          matlab.ui.control.CheckBox
        PPositiveControlExperimentLabel  matlab.ui.control.Label
        NNegativeControlExperimentLabel  matlab.ui.control.Label
        SaveNetworkButton_2             matlab.ui.control.Button
        ReportTab                       matlab.ui.container.Tab
        PreviewPanel                    matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        ScoreGaugeLabel                 matlab.ui.control.Label
        ScoreGauge                      matlab.ui.control.SemicircularGauge
        MoleculescoreListBoxLabel       matlab.ui.control.Label
        MoleculescoreListBox            matlab.ui.control.ListBox
        HoldCtrlformultipleselectionLabel_2  matlab.ui.control.Label
        UIAxes_6                        matlab.ui.control.UIAxes
        ScoreGauge_2Label               matlab.ui.control.Label
        ScoreGauge_2                    matlab.ui.control.SemicircularGauge
        UIAxes_7                        matlab.ui.control.UIAxes
        ScoreGauge_3Label               matlab.ui.control.Label
        ScoreGauge_3                    matlab.ui.control.SemicircularGauge
        UIAxes_8                        matlab.ui.control.UIAxes
        ScoreGauge_4Label               matlab.ui.control.Label
        ScoreGauge_4                    matlab.ui.control.SemicircularGauge
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        FileDropDownLabel               matlab.ui.control.Label
        FileDropDown                    matlab.ui.control.DropDown
        MoleculeCountsPanel             matlab.ui.container.Panel
        CategoryDropDown_2              matlab.ui.control.DropDown
        CategoryDropDown_2Label         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ExportCurrentReportButtonGroup  matlab.ui.container.ButtonGroup
        AllFilesButton_2                matlab.ui.control.RadioButton
        SelectedFilesButton_2           matlab.ui.control.RadioButton
        ExportButton_2                  matlab.ui.control.Button
        ScoreEditField                  matlab.ui.control.NumericEditField
        ScoreThresholdSliderLabel       matlab.ui.control.Label
        ScoreThresholdSlider            matlab.ui.control.Slider
        ExportTracesButtonGroup         matlab.ui.container.ButtonGroup
        AllFilesButton                  matlab.ui.control.RadioButton
        SelectedFilesButton             matlab.ui.control.RadioButton
        ExportButton_3                  matlab.ui.control.Button
        ExportTracesCategoryDropDown_2  matlab.ui.control.DropDown
        ShowFilePathCheckBox            matlab.ui.control.CheckBox
        AllFilesCheckBox                matlab.ui.control.CheckBox
        UIAxes7                         matlab.ui.control.UIAxes
        CopySelectedDataCtrlCButton     matlab.ui.control.Button
        FiledirectoryEditFieldLabel     matlab.ui.control.Label
        FiledirectoryEditField          matlab.ui.control.EditField
    end


    properties (Access = public)
        dataLoaded % loaded data
        fileLoaded % loaded filenames
        currentFileStorage %store value for currentFile
        fileToLoad % files waiting to be loaded
        score % ML classification score
        toClipboard % data to be flushed to clipboar
    end
    
    properties (Access = private)
        model % ML model
        stopTraining % stop training indicator
    end
    
    properties (Access = private, Dependent)
        currentFile % current file index
    end
    
    methods
        function value = get.currentFile(app) % get method for currentFile
            if ~isempty(app.currentFileStorage) && app.currentFileStorage>0
                value = app.currentFileStorage;
            elseif ~isempty(app.fileLoaded)
                value = 1;
            else
                value = 0;
            end
        end
        function app = set.currentFile(app, value) % set method for currentFile
            app.currentFileStorage = value;
        end
    end
    
    methods (Access = private)
    
        function results = applyModel(app, data)
            d = app.model.Layers(1).InputSize;
            X = cell(size(data,1),1);
            for i = 1 : size(data,1)  % mutent
                X{i} = (data(i,:)-mean(data(i,:)))/std(data(i,:));
                X{i} = reshape(X{i},d,[]);
            end
            miniBatchSize = 200;
            [~,s] = classify(app.model,X,...
                'MiniBatchSize',miniBatchSize,...
                'ExecutionEnvironment','cpu');
            results = s(:,1);
        end

        function results = trainModel(app, layer, data, label, maxEpochs)
            if ~iscell(data)
                results = [];
                return
            end

            rnnLayers = layer;
            numberOfWorkers = 1;
            miniBatchSize = 200 * numberOfWorkers;
            options = trainingOptions('adam',...
                'InitialLearnRate',0.002,...
                'MaxEpochs',maxEpochs,...
                'MiniBatchSize',miniBatchSize,...
                'Shuffle','every-epoch',...
                'L2Regularization',0.001,...
                'GradientThreshold',0.1,...
                'Verbose',false,...
                'ExecutionEnvironment','cpu',...
                'OutputFcn',@(info)progressPlot(app,info));

            
            [rnnNet] = trainNetwork(data,label,rnnLayers,options);
            results = rnnNet;
        end
        
        function results = initModel(~, data)
            
            if isempty(data)
                results = [];
                return
            end
            
            class = [1,2];
            inputSize =  size(data{1},1);  % the dimension of the smFRET traces
            numHiddenUnits = 100; % the num of hidden units of the RNN layer
            numClasses = length(class); % classification class: valid frame / invalid frame
            
            rnnLayers = [...
                sequenceInputLayer(inputSize) 
                bilstmLayer(numHiddenUnits,'OutputMode','last')
                fullyConnectedLayer(numClasses)
                softmaxLayer
                simrepsClassificationLayer
                ];  % the layers of RNN
            results = rnnLayers;
        end
        
        function stop = progressPlot(app,info)
            
            persistent lossCurve
            
            stop = false;
            
            % Clear the variables when training starts.
            
            if info.State == "start"
                app.TrainingLamp.Color = 'y';
                app.ApplymodeltodataButton.Enable = 'off';
                lossCurve = [];
                app.StopTrainingButton.Enable = 'on';
                app.stopTraining = false;
                app.StopTrainingButton.BackgroundColor = [0.85,0.33,0.10];
                app.StopTrainingButton.FontColor = [1.00,1.00,1.00];
                app.StartTrainingButton.Enable = 'off';
                app.StartTrainingButton.BackgroundColor = [0.96,0.96,0.96];
                app.StartTrainingButton.FontColor = [0.00,0.00,0.00];
                app.StartTrainingButton.Text = "Resume Training";
            elseif info.State == "iteration" 
                app.TrainingLamp.Color = 'r';
                if ~isempty(info.TrainingLoss)
                    lossCurve = [lossCurve,info.TrainingLoss];
                    lc = conv(lossCurve,ones(1,3)/3,'valid');
                    if mod(info.Iteration,4) == 0
                    t = linspace(0,info.Iteration,length(lc));
                    sp = csaps(t,lc);
                    plot(app.UIAxes9_2, t, fnval(sp,t),...
                        '-b','LineWidth',1.5);
                    yticks(app.UIAxes9_2...
                        ,round(10*linspace(0,ceil(max(lossCurve)),10))/10);
                    ylim(app.UIAxes9_2,[0,ceil(max(lossCurve))]);
                    xlim(app.UIAxes9_2,[0,max(50,ceil(info.Iteration/30)*30)]);
                    end
                end   
            elseif info.State == "done"
                
                app.TrainingLamp.Color = 'g';
                
                app.ApplymodeltodataButton.Enable = 'on';
                
                app.StopTrainingButton.Enable = 'off';
                app.StopTrainingButton.BackgroundColor = [0.96,0.96,0.96];
                app.StopTrainingButton.FontColor = [0.00,0.00,0.00];
                app.StartTrainingButton.Enable = 'on';
                app.StartTrainingButton.BackgroundColor = [0.47,0.67,0.19];
                app.StartTrainingButton.FontColor = [1.00,1.00,1.00];
            end
           
            drawnow;
            pause(0.00001);
            if ~isempty(app.stopTraining) 
                stop = app.stopTraining;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.CustomTrainingPanel.Visible = 'off'; % hide custom training settings            
            app.DataTab.Parent = [];
            app.MLModelTab.Parent = [];
            app.ReportTab.Parent = []; 
            
        end

        % Button pushed function: ChooseMLmodelButton
        function ChooseMLmodelButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MLModelTab;
        end

        % Button pushed function: ApplymodeltodataButton
        function ApplymodeltodataButtonPushed(app, event)
             if isempty(app.fileLoaded)
                 return
             end
             % Apply model to data
             
             pb = uiprogressdlg(app.Single,'Title','Please Wait',...
                   'Message','Loading the Model');
             
             if app.PretrainedRNNButton.Value
                 modelPath = 'simrepsB1.mat';
                 app.model = load(modelPath,'-mat','rnnNet');
                 app.model = app.model.rnnNet;
             elseif app.CustomRNNButton.Value
                 if isempty(app.model)
                    modelPath = 'simrepsB1.mat';
                    app.model = load(modelPath,'-mat','rnnNet');
                    app.model = app.model.rnnNet;
                 else
                     
                 end
             end


             app.score = cell(size(app.dataLoaded));
             for i = 1 : length(app.dataLoaded)
                 pb.Message = strcat('Applying Model to Data', ...
                     ' (',num2str(i),'/',num2str(length(app.dataLoaded)),...
                     ')');
                 pb.Value = min(1,i/length(app.dataLoaded));
                 app.score{i} = applyModel(app, app.dataLoaded{i});
             end
             close(pb);                          
             
             app.ReportTab.Parent = app.TabGroup; % make report tab visible
             
             % prepare report tab (preview panel)
             app.FileDropDown.Items = app.fileLoaded;
             app.FileDropDown.ItemsData = 1 : length(app.fileLoaded);
             app.FileDropDown.Value = app.currentFile;
             ButtonPushed(app, event);
             % prepare report tab (count panel)
             app.TabGroup.SelectedTab = app.ReportTab; % set report tab to current focus
             UpdateFileTable(app, event);
             UITableCellEdit(app, event);
        end

        % Value changed function: ScoreEditField
        function ScoreEditFieldValueChanged(app, event)
            value = app.ScoreEditField.Value;
            app.ScoreThresholdSlider.Value = value;
            if event.Source == app.ScoreEditField
                app.ScoreThresholdSlider.ValueChangedFcn(app, event);
            end
        end

        % Value changed function: ScoreThresholdSlider
        function ScoreThresholdSliderValueChanged(app, event)
            value = app.ScoreThresholdSlider.Value;
            app.ScoreEditField.Value = round(value/0.01)*0.01;
            UpdateFileTable(app, event);
        end

        % Value changing function: ScoreThresholdSlider
        function ScoreThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ScoreEditField.Value = round(changingValue/0.01)*0.01;
            UpdateFileTable(app, event);
        end

        % Button pushed function: ChoosePathButton
        function ChoosePathButtonPushed(app, event)
            app.Single.Visible = 'off';
            [file,path] = uigetfile({'*.mat','Matlab Data (*.mat)';'*.dat'...
                ,'Custom Data (*.dat)';'*.*','All files (*.*)'},...
                'Select a data file',...
                'MultiSelect', 'on');
            app.Single.Visible = 'on';
            if ~isempty(file)
                if iscell(file)
%                     app.FiledirectoryEditField.Value = 'Multiple files';
                    app.fileToLoad = fullfile(path,file);
                    app.LoadDataButton.Enable = 'on';
                elseif ischar(file)
%                     app.FiledirectoryEditField.Value = ...
%                         fullfile(path,file);
                    app.fileToLoad = {fullfile(path,file)};
                    app.LoadDataButton.Enable = 'on';
                end                    
            end

        end

        % Button pushed function: LoadDataButton, LoadDemoDataButton
        function LoadButtonPushed(app, event)
            
            if event.Source == app.LoadDemoDataButton
                app.fileToLoad = {
                    '50 fM MUT n1.tif_whole_traces.mat';
                    '50 fM MUT n2.tif_whole_traces.mat';
                    '50 fM MUT n3.tif_whole_traces.mat';
                    '50 nM WT n1.tif_whole_traces.mat';
                    '50 nM WT n2.tif_whole_traces.mat';
                    '50 nM WT n3.tif_whole_traces.mat';
                    '50 nM WT n4.tif_whole_traces.mat';
                    '50 nM WT n5.tif_whole_traces.mat';
                    '50 nM WT n6.tif_whole_traces.mat';
                    '50 nM WT n7.tif_whole_traces.mat';
                    '50 nM WT n8.tif_whole_traces.mat';
                };
            end
            
%             if isempty(app.fileToLoad)
%                 return
%             end
            
            for i = 1 : length(app.fileToLoad)
                file = app.fileToLoad{i};
                d = load(file,'-mat');
                d = d.traces;
                
                if isempty(app.dataLoaded)
                    app.dataLoaded = {d};
                else
                    app.dataLoaded{end+1} = d;
                end
                
                if isempty(app.fileLoaded)
                    app.fileLoaded = {file};
                else
                    app.fileLoaded{end+1} = file;
                end
            end
            
%             app.currentFile = 1;
            app.CurrentFlieEditField.Value = app.fileLoaded{app.currentFile};

            namelist = cell(size(app.dataLoaded{end},1),1);
            for i = 1 : size(app.dataLoaded{end},1)
                namelist{i} = strcat(num2str(i));
            end
            app.MoleculeListListBox_2.Items = namelist;
            app.MoleculeListListBox_2.ItemsData = 1:size(namelist,1);
            
            app.FilesListBox.Items = app.fileLoaded;
            app.FilesListBox.ItemsData = 1 : length(app.fileLoaded);
            
            app.fileToLoad = [];
            app.LoadDataButton.Enable = 'off';
            
        end

        % Button pushed function: DeleteFileButton
        function DeleteFileButtonPushed(app, event)
            value = app.FilesListBox.Value;
            if ~isempty(value) && value > 0
                app.fileLoaded(value) = [];
                app.dataLoaded(value) = [];
                if ~isempty(app.score)
                    app.score(value) = [];
                end
                app.FilesListBox.Items = app.fileLoaded;
                app.FilesListBox.ItemsData = 1 : length(app.fileLoaded);
                if app.currentFile >= value
                    
                    app.currentFile = app.currentFile - 1;
                    
                    if app.currentFile > 0
                        app.CurrentFlieEditField.Value = app.fileLoaded{app.currentFile};
                        ButtonPushed(app, event);
                        MoleculeListPlot(app, event);
                    else
                        app.CurrentFlieEditField.Value = '';
                    end
                    
                end
            end
            
            app.FileDropDown.Items = app.fileLoaded;
            app.FileDropDown.ItemsData = 1 : length(app.fileLoaded);
            UpdateFileTable(app, event);
        end

        % Callback function: ExposureTimeEditField, 
        % MoleculeListListBox_2, MoleculescoreListBox, 
        % XAxisUnitButtonGroup
        function MoleculeListPlot(app, event)
            selectedButton = app.XAxisUnitButtonGroup.SelectedObject;          
            if strcmp(selectedButton.Text, 'Frames')
                app.ExposureTimeEditField.Enable = 'off';
                dt = 1;
                label = 'Frame';
            else
                app.ExposureTimeEditField.Enable = 'on';
                dt = app.ExposureTimeEditField.Value;
                label = 'Time';
            end
            
            if event.Source.Parent == app.DataTab ...
                    || event.Source.Parent == app.SettingsPanel
                axis = {app.UIAxes1,app.UIAxes2,app.UIAxes3};
                scoreGauge = {};
                value = app.MoleculeListListBox_2.Value;
            elseif event.Source.Parent == app.PreviewPanel ...
                    || event.Source == app.ApplymodeltodataButton
                axis = {app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,...
                            app.UIAxes_8};
                scoreGauge = {app.ScoreGauge, app.ScoreGauge_2, ...
                    app.ScoreGauge_3, app.ScoreGauge_4};
                value = app.MoleculescoreListBox.Value;
            else
                value = [];
                axis = {};
                scoreGauge = {};   
            end
            
            for i = length(value)+1 : length(axis)
                cla(axis{i});
                if ~isempty(scoreGauge)
                    gauge = scoreGauge{i};
                    gauge.Value = 0;
                end
            end
            
            if ~isempty(app.dataLoaded)
                for i = 1 : min(length(value),length(axis))
                    n = value(i);
                    time = dt * [0:length(app.dataLoaded{app.currentFile}(n,:))-1];
                    plot(axis{i},time,app.dataLoaded{app.currentFile}(n,:),'k-');
                    if ~isempty(scoreGauge)
                        if ~isempty(scoreGauge)
                            gauge = scoreGauge{i};
                            gauge.Value = app.score{app.currentFile}(n);
                        end
                    end
                end   
            end
            
            for i = 1 : length(axis)
                xlabel(axis{i},label);
            end  
        end

        % Callback function: Button, Button_2, Button_3, Button_4, 
        % FileDropDown
        function ButtonPushed(app, event)
            changed = false;
            
            if isempty(app.fileLoaded)
                app.CurrentFlieEditField.Value = '';
                return
            end
            
            hasText = ~isempty(event.Source.findprop('Text'));
            
            if hasText&&strcmp(event.Source.Text,'>') &&...
                app.currentFile < length(app.dataLoaded)
                app.currentFile = app.currentFile + 1;
                changed = true;
            elseif hasText&&strcmp(event.Source.Text,'<') &&...
                app.currentFile > 1
                app.currentFile = app.currentFile - 1;
                changed = true;
            elseif event.Source == app.DeleteFileButton
                changed = true;
            elseif event.Source == app.ApplymodeltodataButton
                changed = true;
            elseif event.Source == app.FileDropDown
                changed = true;
                app.currentFile = event.Value;
            end


            if changed
                if event.Source.Parent == app.DataTab % if button in data tab
                    app.CurrentFlieEditField.Value = app.fileLoaded{app.currentFile};
                    namelist = cell(size(app.dataLoaded{app.currentFile},1),1);
                    for i = 1 : size(app.dataLoaded{app.currentFile},1)
                        namelist{i} = strcat(num2str(i));
                    end
                    app.MoleculeListListBox_2.Items = namelist;
                    app.MoleculeListListBox_2.ItemsData = 1:size(namelist,1);
                    MoleculeListPlot(app, event);
                elseif event.Source.Parent == app.PreviewPanel ...
                        || event.Source == app.ApplymodeltodataButton
                    app.FileDropDown.Value = app.currentFile;
                    namelist = cell(size(app.dataLoaded{app.currentFile},1),1);
                    for i = 1 : size(app.dataLoaded{app.currentFile},1)
                        namelist{i} = strcat(num2str(i),' ('...
                            ,num2str(round(app.score{app.currentFile}(i)*100)/100),')');
                    end
                    app.MoleculescoreListBox.Items = namelist;
                    app.MoleculescoreListBox.ItemsData = 1:size(namelist,1);
                    MoleculeListPlot(app, event);
                end
            end
        end

        % Value changed function: AllFilesCheckBox, 
        % ShowFilePathCheckBox
        function UpdateFileTable(app, event)
            if isempty(app.score)
                return  
            end
            showPath = logical(app.ShowFilePathCheckBox.Value);
            allSelect = logical(app.AllFilesCheckBox.Value);
            t = cell(length(app.fileLoaded),5);
             for i = 1 : length(app.fileLoaded)
                 if ~showPath
                    [~,name]=fileparts(app.fileLoaded{i});
                    t(i,:) = {allSelect,...
                        name,...
                        sum(app.score{i}>app.ScoreEditField.Value),...
                        sum(app.score{i}<=app.ScoreEditField.Value),...
                        length(app.score{i})};
                 else
                    t(i,:) = {allSelect,...
                        app.fileLoaded{i},...
                        sum(app.score{i}>app.ScoreEditField.Value),...
                        sum(app.score{i}<=app.ScoreEditField.Value),...
                        length(app.score{i})};
                 end
             end
             
             if size(t,1) ~= size(app.UITable.Data,1)
                 app.UITable.Data = t;
             elseif event.Source == app.ShowFilePathCheckBox
                     app.UITable.Data(:,2:end) = t(:,2:end);
             elseif event.Source == app.ScoreThresholdSlider ...
                     || event.Source == app.ScoreEditField
                 app.UITable.Data(:,3:end) = t(:,3:end);
             else
                 app.UITable.Data = t;
             end
             
             UITableCellEdit(app, event);
             
        end

        % Callback function: CategoryDropDown_2, UITable
        function UITableCellEdit(app, event)
            d = [];
            n = 0;
            for i = 1 : length(app.score)
                if app.UITable.Data{i,1}
                    d(n+1:n+length(app.score{i})) = app.score{i};
                    n = n + length(app.score{i});
                end
            end           
            if strcmp(app.CategoryDropDown_2.Value,'Accepted+Rejected')
                histogram(app.UIAxes7,d,'BinWidth',0.02);
                xlim(app.UIAxes7,[0,1]);
            elseif strcmp(app.CategoryDropDown_2.Value, 'Accepted')
                histogram(app.UIAxes7,...
                    d(d>app.ScoreEditField.Value),...
                    'BinWidth',0.02);
                xlim(app.UIAxes7,[0,1]);
            elseif strcmp(app.CategoryDropDown_2.Value, 'Rejected')
                histogram(app.UIAxes7,...
                    d(d<= app.ScoreEditField.Value),...
                    'BinWidth',0.02);
                xlim(app.UIAxes7,[0,1]);
            end
            set(app.UIAxes7,'XScale','linear');
            set(app.UIAxes7,'YScale','log');
            xlabel(app.UIAxes7,'score');
            ylabel(app.UIAxes7,'count');
            title(app.UIAxes7,'score distribution');
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if strcmp(event.EventName,'CellSelection')
                s = [];
                line = sum(event.Indices(:,1) == event.Indices(1,1));                             
                for i = 1 : size(event.Indices,1)
                        m1 = event.Indices(i,1);
                        m2 = event.Indices(i,2);
                        if ischar(app.UITable.Data{m1,m2})
                            s = [s,double(app.UITable.Data{m1,m2})];
                        else
                            s = [s,double(num2str(app.UITable.Data{m1,m2}))];
                        end
                    if mod(i,line) ~= 0
                        s = [s,9];
                    else    
                        s = [s,10];
                    end
                end
                app.toClipboard = char(s);
            end
        end

        % Callback function: CopyMenu, CopySelectedDataCtrlCButton
        function CopySelectedDataCtrlCButtonPushed(app, event)
            clipboard('copy',app.toClipboard);
        end

        % Menu selected function: ImportFilesMenu
        function ImportFilesMenuSelected(app, event)
            app.ChoosePathButton.ButtonPushedFcn(app, event);
            app.LoadDataButton.ButtonPushedFcn(app, event);
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            app.Single.Visible = 'off';
            [file,path] = uiputfile('.mlprj','Save Project','default');
            app.Single.Visible = 'on';
            
            fd = fieldnames(app);
            target = {'Data','Items','ItemsData','Value','Enable',...
                'Visible','Text'};
            g=struct();
            for i = 1 : length(fd)
                fn = fd{i};
                if ~isempty(app.findprop(fn))
                    if iscell(app.(fn)) || isnumeric(app.(fn)) ...
                            || isa(app.(fn),'SeriesNetwork')
                        g.(fn) = app.(fn);
                    elseif isobject(app.(fn)) ...
                            && ismethod(app.(fn),'findprop')
                        for j = 1 : length(target)
                            t = target{j};
                            if ~isempty(app.(fn).findprop(t))
                                g.(fn).(t) = app.(fn).(t); 
                            end
                        end
                    end
                end
            end

            save(strcat(path,file),'g','-mat');
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            app.Single.Visible = 'off';
            [file,path] = uigetfile({'*.mlprj','Project File (*.prj)';...
                '*.*','All files (*.*)'},...
                'Select a Project File',...
                'MultiSelect', 'off');
            app.Single.Visible = 'on';
            
            if isempty(file)
                return
            end
            
            warning off
            d = load(strcat(path,file),'-mat','g');
            d = d.g;
            warning on
            
            if isfield(d,'score') && ~isempty(d.score)
                app.ReportTab.Parent = app.TabGroup;
            end
            
            fd = fieldnames(app);
            target = {'Data','Items','ItemsData','Value','Enable',...
                'Visible','Text'};
            for i = 1 : length(fd)
                fn = fd{i};
                if isfield(d,fn)
                    if iscell(d.(fn)) || isnumeric(d.(fn)) ...
                            || isa(d.(fn),'SeriesNetwork')
                        app.(fn) = d.(fn);
                    elseif isstruct(d.(fn))
                        for j = 1 : length(target)
                            t = target{j};
                            if isfield(d.(fn),t)
                                app.(fn).(t) = d.(fn).(t); 
                            end
                        end
                    end
                end
            end            
        end

        % Button pushed function: LogInButton
        function LogInButtonPushed(app, event)
            user = 'mlanalysis';
            pass = 'mlanalysis';
            if strcmp(app.UsernameEditField.Value,user) ...
                    && strcmp(app.PasswordEditField.Value,pass)
                app.DataTab.Parent = app.TabGroup;
                app.MLModelTab.Parent = app.TabGroup;
%                 app.ReportTab.Parent = app.TabGroup;
                app.LoginTab.Parent = [];
            end
        end

        % Callback function: AllFilesCheckBox_2, ModelsButtonGroup, 
        % ShowFilePathCheckBox_2
        function ModelsButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.ModelsButtonGroup.SelectedObject;
            if selectedButton == app.PretrainedRNNButton
                app.CustomTrainingPanel.Visible = 'off';
                app.ApplymodeltodataButton.Enable = 'on';
            elseif selectedButton == app.CustomRNNButton
                
                pb = uiprogressdlg(app.Single,'Title','Please Wait',...
                   'Message','Loading Custom Settings');
               
                if event.Source == app.ModelsButtonGroup
                    app.ApplymodeltodataButton.Enable = 'off';
                    app.StartTrainingButton.Text = "Start Training";
                    app.model = [];
                end
                
                pb.Value = 0.2;
                
                % update training set selection table
                if isempty(app.fileLoaded)
                    return
                end
                
                showpath = logical(app.ShowFilePathCheckBox_2.Value);
                allfile = logical(app.AllFilesCheckBox_2.Value);
                t = cell(length(app.fileLoaded),3);
                for i = 1 : size(t,1)
                    if showpath
                        t{i,3} = app.fileLoaded{i};
                    else
                        [~,name] = fileparts(app.fileLoaded{i});
                        t{i,3} = name;
                    end
                    if allfile
                        t{i,1} = true;
                    else
                        t{i,1} = false;
                    end
                    t{i,2} = categorical({'P'},{'P','N'});
                end
                tb = cell2table(t);
                
                pb.Value = 0.6;
                % assigning to data
                if event.Source == app.ModelsButtonGroup
                    app.TrainSetTable.Data = tb;
                end
                
                if size(app.TrainSetTable.Data,1) ~= size(tb,1)
                    app.TrainSetTable.Data = tb;
                elseif istable(app.TrainSetTable.Data)
                    if event.Source == app.AllFilesCheckBox_2
                        app.TrainSetTable.Data(:,1) = tb(:,1);
                    elseif event.Source == app.ShowFilePathCheckBox_2
                        app.TrainSetTable.Data(:,3) = tb(:,3);
                    end
                end
                app.TrainSetTable.ColumnEditable = [true,true,false];
                pb.Value = 0.9;
            
                app.CustomTrainingPanel.Visible = 'on';
                pb.Value = 1.0;
                close(pb);
            end
           
        end

        % Cell edit callback: TrainSetTable
        function TrainSetTableCellEdit(app, event)

        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            
            if isempty(app.fileLoaded)
                return
            end
                
            app.stopTraining = false;
            
            %prepare data
            d = 20;
            nx = 0;
            for i = 1 : length(app.fileLoaded)
                if app.TrainSetTable.Data{i,1}
                    nx = nx + size(app.dataLoaded{i},1);
                end
            end
            
            if nx == 0
                return
            end
            
            X = cell(nx,1);
            Y = zeros(nx,1);
            nx = 0;
            for i = 1 : length(app.fileLoaded)
                if app.TrainSetTable.Data{i,1}
                    data = app.dataLoaded{i};
                    for j = 1 : size(data,1)  
                        nx = nx + 1;
                        X{nx} = (data(j,:)-mean(data(j,:)))...
                            /std(data(j,:));
                        X{nx} = reshape(X{nx},d,[]);
                        Y(nx) = app.TrainSetTable.Data{i,2};
                    end
                end
            end
            Y = categorical(Y);           
            
            if isempty(app.model)
                
                pb = uiprogressdlg(app.Single,'Title','Please Wait',...
                   'Message','Warming Up the Model');
                pb.Value = 0.5;
                
                layer = initModel(app, X);
                
                warmup = 6;
                rnn = trainModel(app, layer, X, Y, warmup);
            
                pb.Value = 1;
                close(pb);
                
            else
                rnn = app.model;
            end
            
            maxepoch = 60;
            rnn = trainModel(app, rnn.Layers, X, Y, maxepoch);
            
            app.model = rnn;
            
            if ~isempty(rnn)
                app.ApplymodeltodataButton.Enable = 'on';
            end
            
            
        end

        % Button pushed function: StopTrainingButton
        function StopTrainingButtonPushed(app, event)
            
            app.stopTraining = true;
            
        end

        % Button pushed function: ResetNetworkButton
        function ResetNetworkButtonPushed(app, event)
            app.model = [];
            app.StartTrainingButton.Text = "Start Training";
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.MLModelTab
                ModelsButtonGroupSelectionChanged(app, event);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Single = uifigure;
            app.Single.Name = 'Single Channel Data Analysis';
            app.FileMenu = uimenu(app.Single);
            app.FileMenu.Text = 'File';
            app.ImportFilesMenu = uimenu(app.FileMenu);
            app.ImportFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportFilesMenuSelected, true);
            app.ImportFilesMenu.Accelerator = 'I';
            app.ImportFilesMenu.Text = 'Import Files';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Accelerator = 's';
            app.SaveProjectMenu.Text = 'Save Project';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Accelerator = 'o';
            app.OpenProjectMenu.Text = 'Open Project';
            app.EditMenu = uimenu(app.Single);
            app.EditMenu.Text = 'Edit';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopySelectedDataCtrlCButtonPushed, true);
            app.CopyMenu.Accelerator = 'C';
            app.CopyMenu.Text = 'Copy';
            app.HelpMenu = uimenu(app.Single);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.DataFormatMenu = uimenu(app.HelpMenu);
            app.DataFormatMenu.Text = 'Data Format';
            app.DocumentsMenu = uimenu(app.HelpMenu);
            app.DocumentsMenu.Text = 'Documents';
            app.TabGroup = uitabgroup(app.Single);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoginTab = uitab(app.TabGroup);
            app.LoginTab.Title = 'Login';
            app.LoginPanel = uipanel(app.LoginTab);
            app.LoginPanel.Title = 'Login';
            app.UsernameEditFieldLabel = uilabel(app.LoginPanel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.LoginPanel, 'text');
            app.UsernameEditField.Value = 'mlanalysis';
            app.PasswordEditFieldLabel = uilabel(app.LoginPanel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.LoginPanel, 'text');
            app.PasswordEditField.Value = 'mlanalysis';
            app.LogInButton = uibutton(app.LoginPanel, 'push');
            app.LogInButton.ButtonPushedFcn = createCallbackFcn(app, @LogInButtonPushed, true);
            app.LogInButton.Text = 'Log In';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.LoadDataButton = uibutton(app.DataTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.Button = uibutton(app.DataTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.Button_2 = uibutton(app.DataTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Text = '<';
            app.SettingsPanel = uipanel(app.DataTab);
            app.SettingsPanel.Title = 'Settings';
            app.XAxisUnitButtonGroup = uibuttongroup(app.SettingsPanel);
            app.XAxisUnitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MoleculeListPlot, true);
            app.XAxisUnitButtonGroup.Title = 'X Axis Unit';
            app.FramesButton = uiradiobutton(app.XAxisUnitButtonGroup);
            app.FramesButton.Text = 'Frames';
            app.TimeButton = uiradiobutton(app.XAxisUnitButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.ExposureTimeEditFieldLabel = uilabel(app.XAxisUnitButtonGroup);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.XAxisUnitButtonGroup, 'numeric');
            app.ExposureTimeEditField.Limits = [0 Inf];
            app.ExposureTimeEditField.ValueChangedFcn = createCallbackFcn(app, @MoleculeListPlot, true);
            app.ExposureTimeEditField.Value = 0.5;
            app.YAxisUnitButtonGroup = uibuttongroup(app.SettingsPanel);
            app.YAxisUnitButtonGroup.Title = 'Y Axis Unit';
            app.FluorescenceIntensityButton = uiradiobutton(app.YAxisUnitButtonGroup);
            app.FluorescenceIntensityButton.Text = {'Fluorescence'; 'Intensity'};
            app.FluorescenceIntensityButton.Value = true;
            app.CurrentFlieEditFieldLabel = uilabel(app.DataTab);
            app.CurrentFlieEditFieldLabel.Text = 'Current Flie';
            app.CurrentFlieEditField = uieditfield(app.DataTab, 'text');
            app.CurrentFlieEditField.Editable = 'off';
            app.ChooseMLmodelButton = uibutton(app.DataTab, 'push');
            app.ChooseMLmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseMLmodelButtonPushed, true);
            app.ChooseMLmodelButton.Text = 'Choose ML model';
            app.ChoosePathButton = uibutton(app.DataTab, 'push');
            app.ChoosePathButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePathButtonPushed, true);
            app.ChoosePathButton.Text = 'Select Data';
            app.MoleculeListListBox_2Label = uilabel(app.DataTab);
            app.MoleculeListListBox_2Label.Text = 'Molecule List';
            app.MoleculeListListBox_2 = uilistbox(app.DataTab);
            app.MoleculeListListBox_2.Items = {'1', '2', '3', '4'};
            app.MoleculeListListBox_2.Multiselect = 'on';
            app.MoleculeListListBox_2.ValueChangedFcn = createCallbackFcn(app, @MoleculeListPlot, true);
            app.MoleculeListListBox_2.Value = {'1'};
            app.HoldCtrlformultipleselectionLabel = uilabel(app.DataTab);
            app.HoldCtrlformultipleselectionLabel.Text = {'Hold Ctrl/⌘ for '; 'multiple selection'};
            app.DeleteFileButton = uibutton(app.DataTab, 'push');
            app.DeleteFileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFileButtonPushed, true);
            app.DeleteFileButton.Text = 'Delete File';
            app.FilesListBoxLabel = uilabel(app.DataTab);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.DataTab);
            app.FilesListBox.Items = {};
            app.FilesListBox.Value = {};
            app.DataReadingCheckPanel = uipanel(app.DataTab);
            app.DataReadingCheckPanel.Title = 'Data Reading Check';
            app.UIAxes1 = uiaxes(app.DataReadingCheckPanel);
            xlabel(app.UIAxes1, 'Time')
            ylabel(app.UIAxes1, 'Intensity')
            app.UIAxes2 = uiaxes(app.DataReadingCheckPanel);
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Intensity')
            app.UIAxes3 = uiaxes(app.DataReadingCheckPanel);
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Intensity')
            app.LoadDemoDataButton = uibutton(app.DataTab, 'push');
            app.LoadDemoDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadDemoDataButton.Text = 'Load Demo Data';
            app.MLModelTab = uitab(app.TabGroup);
            app.MLModelTab.Title = 'ML Model';
            app.ModelsButtonGroup = uibuttongroup(app.MLModelTab);
            app.ModelsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelsButtonGroupSelectionChanged, true);
            app.ModelsButtonGroup.Title = 'Models';
            app.PretrainedRNNButton = uiradiobutton(app.ModelsButtonGroup);
            app.PretrainedRNNButton.Text = 'Pre-trained RNN';
            app.PretrainedRNNButton.Value = true;
            app.CustomRNNButton = uiradiobutton(app.ModelsButtonGroup);
            app.CustomRNNButton.Text = 'Custom RNN';
            app.ApplymodeltodataButton = uibutton(app.MLModelTab, 'push');
            app.ApplymodeltodataButton.ButtonPushedFcn = createCallbackFcn(app, @ApplymodeltodataButtonPushed, true);
            app.ApplymodeltodataButton.Text = 'Apply model to data';
            app.CustomTrainingPanel = uipanel(app.MLModelTab);
            app.CustomTrainingPanel.Title = 'Custom Training';
            app.UIAxes9_2 = uiaxes(app.CustomTrainingPanel);
            title(app.UIAxes9_2, 'Training Progress')
            xlabel(app.UIAxes9_2, 'Iteration')
            ylabel(app.UIAxes9_2, 'Loss')
            app.UIAxes9_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.StartTrainingButton = uibutton(app.CustomTrainingPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.TrainingLampLabel = uilabel(app.CustomTrainingPanel);
            app.TrainingLampLabel.Text = 'Training  ';
            app.TrainingLamp = uilamp(app.CustomTrainingPanel);
            app.StopTrainingButton = uibutton(app.CustomTrainingPanel, 'push');
            app.StopTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrainingButtonPushed, true);
            app.StopTrainingButton.Enable = 'off';
            app.StopTrainingButton.Text = 'Stop Training';
            app.ResetNetworkButton = uibutton(app.CustomTrainingPanel, 'push');
            app.ResetNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ResetNetworkButtonPushed, true);
            app.ResetNetworkButton.Text = 'Reset Network';
            app.TrainSetTable = uitable(app.CustomTrainingPanel);
            app.TrainSetTable.ColumnName = {'Include'; 'Label'; 'Filename'};
            app.TrainSetTable.RowName = {};
            app.TrainSetTable.ColumnEditable = [true true false];
            app.TrainSetTable.CellEditCallback = createCallbackFcn(app, @TrainSetTableCellEdit, true);
            app.AllFilesCheckBox_2 = uicheckbox(app.CustomTrainingPanel);
            app.AllFilesCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ModelsButtonGroupSelectionChanged, true);
            app.AllFilesCheckBox_2.Text = 'All Files';
            app.ShowFilePathCheckBox_2 = uicheckbox(app.CustomTrainingPanel);
            app.ShowFilePathCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ModelsButtonGroupSelectionChanged, true);
            app.ShowFilePathCheckBox_2.Text = 'Show File Path';
            app.PPositiveControlExperimentLabel = uilabel(app.CustomTrainingPanel);
            app.PPositiveControlExperimentLabel.Text = 'P = Positive Control Experiment';
            app.NNegativeControlExperimentLabel = uilabel(app.CustomTrainingPanel);
            app.NNegativeControlExperimentLabel.Text = 'N = Negative Control Experiment';
            app.SaveNetworkButton_2 = uibutton(app.CustomTrainingPanel, 'push');
            app.SaveNetworkButton_2.Text = 'Save Network';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.PreviewPanel = uipanel(app.ReportTab);
            app.PreviewPanel.Title = 'Preview';
            app.UIAxes_5 = uiaxes(app.PreviewPanel);
            xlabel(app.UIAxes_5, 'Time')
            ylabel(app.UIAxes_5, 'Intensity')
            app.ScoreGaugeLabel = uilabel(app.PreviewPanel);
            app.ScoreGaugeLabel.Text = 'Score';
            app.ScoreGauge = uigauge(app.PreviewPanel, 'semicircular');
            app.ScoreGauge.Limits = [0 1];
            app.ScoreGauge.ScaleDirection = 'counterclockwise';
            app.MoleculescoreListBoxLabel = uilabel(app.PreviewPanel);
            app.MoleculescoreListBoxLabel.Text = 'Molecule (score）';
            app.MoleculescoreListBox = uilistbox(app.PreviewPanel);
            app.MoleculescoreListBox.Items = {};
            app.MoleculescoreListBox.Multiselect = 'on';
            app.MoleculescoreListBox.ValueChangedFcn = createCallbackFcn(app, @MoleculeListPlot, true);
            app.MoleculescoreListBox.Value = {};
            app.HoldCtrlformultipleselectionLabel_2 = uilabel(app.PreviewPanel);
            app.HoldCtrlformultipleselectionLabel_2.Text = {'Hold Ctrl/⌘ for '; 'multiple selection'};
            app.UIAxes_6 = uiaxes(app.PreviewPanel);
            xlabel(app.UIAxes_6, 'Time')
            ylabel(app.UIAxes_6, 'Intensity')
            app.ScoreGauge_2Label = uilabel(app.PreviewPanel);
            app.ScoreGauge_2Label.Text = 'Score';
            app.ScoreGauge_2 = uigauge(app.PreviewPanel, 'semicircular');
            app.ScoreGauge_2.Limits = [0 1];
            app.ScoreGauge_2.ScaleDirection = 'counterclockwise';
            app.UIAxes_7 = uiaxes(app.PreviewPanel);
            xlabel(app.UIAxes_7, 'Time')
            ylabel(app.UIAxes_7, 'Intensity')
            app.ScoreGauge_3Label = uilabel(app.PreviewPanel);
            app.ScoreGauge_3Label.Text = 'Score';
            app.ScoreGauge_3 = uigauge(app.PreviewPanel, 'semicircular');
            app.ScoreGauge_3.Limits = [0 1];
            app.ScoreGauge_3.ScaleDirection = 'counterclockwise';
            app.UIAxes_8 = uiaxes(app.PreviewPanel);
            xlabel(app.UIAxes_8, 'Time')
            ylabel(app.UIAxes_8, 'Intensity')
            app.ScoreGauge_4Label = uilabel(app.PreviewPanel);
            app.ScoreGauge_4Label.Text = 'Score';
            app.ScoreGauge_4 = uigauge(app.PreviewPanel, 'semicircular');
            app.ScoreGauge_4.Limits = [0 1];
            app.ScoreGauge_4.ScaleDirection = 'counterclockwise';
            app.Button_3 = uibutton(app.PreviewPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Text = '<';
            app.Button_4 = uibutton(app.PreviewPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_4.Text = '>';
            app.FileDropDownLabel = uilabel(app.PreviewPanel);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.PreviewPanel);
            app.FileDropDown.Items = {};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.FileDropDown.Value = {};
            app.MoleculeCountsPanel = uipanel(app.ReportTab);
            app.MoleculeCountsPanel.Title = 'Molecule Counts';
            app.CategoryDropDown_2 = uidropdown(app.MoleculeCountsPanel);
            app.CategoryDropDown_2.Items = {'Accepted+Rejected', 'Accepted', 'Rejected'};
            app.CategoryDropDown_2.ValueChangedFcn = createCallbackFcn(app, @UITableCellEdit, true);
            app.CategoryDropDown_2.Value = 'Accepted+Rejected';
            app.CategoryDropDown_2Label = uilabel(app.MoleculeCountsPanel);
            app.CategoryDropDown_2Label.Text = 'Category';
            app.UITable = uitable(app.MoleculeCountsPanel);
            app.UITable.ColumnName = {''; 'File'; 'Accepted'; 'Rejected'; 'Total'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ExportCurrentReportButtonGroup = uibuttongroup(app.MoleculeCountsPanel);
            app.ExportCurrentReportButtonGroup.Title = 'Export Current Report';
            app.AllFilesButton_2 = uiradiobutton(app.ExportCurrentReportButtonGroup);
            app.AllFilesButton_2.Text = 'All Files';
            app.AllFilesButton_2.Value = true;
            app.SelectedFilesButton_2 = uiradiobutton(app.ExportCurrentReportButtonGroup);
            app.SelectedFilesButton_2.Text = 'Selected Files';
            app.ExportButton_2 = uibutton(app.ExportCurrentReportButtonGroup, 'push');
            app.ExportButton_2.Text = 'Export';
            app.ScoreEditField = uieditfield(app.MoleculeCountsPanel, 'numeric');
            app.ScoreEditField.Limits = [0 1];
            app.ScoreEditField.ValueDisplayFormat = '%2.2f';
            app.ScoreEditField.ValueChangedFcn = createCallbackFcn(app, @ScoreEditFieldValueChanged, true);
            app.ScoreEditField.Value = 0.5;
            app.ScoreThresholdSliderLabel = uilabel(app.MoleculeCountsPanel);
            app.ScoreThresholdSliderLabel.Text = {'Score'; 'Threshold'};
            app.ScoreThresholdSlider = uislider(app.MoleculeCountsPanel);
            app.ScoreThresholdSlider.Limits = [0 1];
            app.ScoreThresholdSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ScoreThresholdSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.ScoreThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ScoreThresholdSliderValueChanged, true);
            app.ScoreThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ScoreThresholdSliderValueChanging, true);
            app.ScoreThresholdSlider.Value = 0.5;
            app.ExportTracesButtonGroup = uibuttongroup(app.MoleculeCountsPanel);
            app.ExportTracesButtonGroup.Title = 'Export Traces';
            app.AllFilesButton = uiradiobutton(app.ExportTracesButtonGroup);
            app.AllFilesButton.Text = 'All Files';
            app.AllFilesButton.Value = true;
            app.SelectedFilesButton = uiradiobutton(app.ExportTracesButtonGroup);
            app.SelectedFilesButton.Text = 'Selected Files';
            app.ExportButton_3 = uibutton(app.ExportTracesButtonGroup, 'push');
            app.ExportButton_3.Text = 'Export';
            app.ExportTracesCategoryDropDown_2 = uidropdown(app.ExportTracesButtonGroup);
            app.ExportTracesCategoryDropDown_2.Items = {'Accepted', 'All', 'Rejected'};
            app.ExportTracesCategoryDropDown_2.Value = 'Accepted';
            app.ShowFilePathCheckBox = uicheckbox(app.MoleculeCountsPanel);
            app.ShowFilePathCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateFileTable, true);
            app.ShowFilePathCheckBox.Text = 'Show File Path';
            app.AllFilesCheckBox = uicheckbox(app.MoleculeCountsPanel);
            app.AllFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateFileTable, true);
            app.AllFilesCheckBox.Text = 'All Files';
            app.AllFilesCheckBox.Value = true;
            app.UIAxes7 = uiaxes(app.MoleculeCountsPanel);
            title(app.UIAxes7, 'score distribution')
            xlabel(app.UIAxes7, 'score')
            ylabel(app.UIAxes7, 'count')
            app.CopySelectedDataCtrlCButton = uibutton(app.MoleculeCountsPanel, 'push');
            app.CopySelectedDataCtrlCButton.ButtonPushedFcn = createCallbackFcn(app, @CopySelectedDataCtrlCButtonPushed, true);
            app.CopySelectedDataCtrlCButton.Text = 'Copy Selected Data (Ctrl/⌘+C)';
            app.FiledirectoryEditFieldLabel = uilabel(app.Single);
            app.FiledirectoryEditFieldLabel.Text = 'File directory';
            app.FiledirectoryEditField = uieditfield(app.Single, 'text');
            app.FiledirectoryEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = singleChannel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Single)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Single)
        end
    end
end