classdef showResult < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TP            matlab.ui.control.UIAxes
        FP            matlab.ui.control.UIAxes
        FN            matlab.ui.control.UIAxes
        TN            matlab.ui.control.UIAxes
        TP1           matlab.ui.control.Button
        TP0           matlab.ui.control.Button
        FP1           matlab.ui.control.Button
        FP0           matlab.ui.control.Button
        TN1           matlab.ui.control.Button
        TN0           matlab.ui.control.Button
        FN1           matlab.ui.control.Button
        FN0           matlab.ui.control.Button
        scoreLabel_3  matlab.ui.control.Label
        scoreGaugeFN  matlab.ui.control.SemicircularGauge
        scoreLabel    matlab.ui.control.Label
        scoreGaugeTP  matlab.ui.control.SemicircularGauge
        scoreLabel_2  matlab.ui.control.Label
        scoreGaugeFP  matlab.ui.control.SemicircularGauge
        scoreLabel_4  matlab.ui.control.Label
        scoreGaugeTN  matlab.ui.control.SemicircularGauge
    end


    properties (Access = private)
        flag % status flag
        table % table containing results
        window % window position
    end

    methods (Access = private)
    
        function r = changeTrace(app,id,step)
            files = app.table.files;
            label = app.table.YLabelTest;
            pred = app.table.YPred;
            score = app.table.Yscore;
            r = false;
            if strcmp(id,'TP')
                i = app.flag(1) + step;
                while i>=1 && i<=numel(files)
                    if label(i) == 'true' && pred(i) == 'true'
                        data = readTimeTrace(files{i},1);
                        trace = data{1}(1:2,:);
                        app.scoreGaugeTP.Value = 100 * max(score(i,:));
                        plot(app.TP,trace(2,:),'Color',[0,0.5,0]);
                        hold(app.TP,'on');
                        plot(app.TP,trace(1,:),'r');
                        if(numel(data) == 2)
                            region = data{2};
                            plot(app.TP,region' * max(sum(trace(:,region==1))),'b-','LineWidth',2);
                        end
                        hold(app.TP,'off');
                        app.flag(1) = i;
                        r = true;
                        return;
                    end
                    i = i + step;
                end
            elseif strcmp(id,'FP')
                i = app.flag(2) + step;
                while i>=1 && i<=numel(files)
                    if label(i) == 'false' && pred(i) == 'true'
                        data = readTimeTrace(files{i},1);
                        trace = data{1}(1:2,:);
                        app.scoreGaugeFP.Value = 100 * max(score(i,:));
                        plot(app.FP,trace(2,:),'Color',[0,0.5,0]);
                        hold(app.FP,'on');
                        plot(app.FP,trace(1,:),'r');
                        if(numel(data) == 2)
                            region = data{2};
                            plot(app.FP,region' * max(sum(trace(:,region==1))),'b-','LineWidth',2);
                        end
                        hold(app.FP,'off');
                        app.flag(2) = i;
                        r = true;
                        return
                    end
                    i = i + step;
                end
            elseif strcmp(id,'FN')
                i = app.flag(3) + step;
                while i>=1 && i<=numel(files)
                    if label(i) == 'true' && pred(i) == 'false'
                        data = readTimeTrace(files{i},1);
                        trace = data{1}(1:2,:);
                        app.scoreGaugeFN.Value = 100 * max(score(i,:));
                        plot(app.FN,trace(2,:),'Color',[0,0.5,0]);
                        hold(app.FN,'on');
                        plot(app.FN,trace(1,:),'r');
                        if(numel(data) == 2)
                            region = data{2};
                            plot(app.FN,region' * max(sum(trace(:,region==1))),'b-','LineWidth',2);
                        end
                        hold(app.FN,'off');
                        app.flag(3) = i;
                        r = true;
                        return
                    end
                    i = i + step;
                end
            elseif strcmp(id,'TN')
                i = app.flag(4) + step;
                while i>=1 && i<=numel(files)
                    if label(i) == 'false' && pred(i) == 'false'
                        data = readTimeTrace(files{i},1);
                        trace = data{1}(1:2,:);
                        app.scoreGaugeTN.Value = 100 * max(score(i,:));
                        plot(app.TN,trace(2,:),'Color',[0,0.5,0]);
                        hold(app.TN,'on');
                        plot(app.TN,trace(1,:),'r');
                        if(numel(data) == 2)
                            region = data{2};
                            plot(app.TN,region' * max(sum(trace(:,region==1))),'b-','LineWidth',2);
                        end
                        hold(app.TN,'off');
                        app.flag(4) = i;
                        r = true;
                        return
                    end
                    i = i + step;                    
                end
            end
        end       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, T)
            app.table = T;
            files = T.files;
            label = T.YLabelTest;
            pred = T.YPred;
            score = T.Yscore;
            app.flag = [0,0,0,0];
            for i = 1 : numel(files)
                if label(i) == 'true' && pred(i) == 'true' && ~app.flag(1)
                    app.flag(1) = i;
                    data = readTimeTrace(files{i},1);
                    trace = data{1}(1:2,:);
                    app.scoreGaugeTP.Value = 100 * max(score(i,:));
                    plot(app.TP,trace(2,:),'Color',[0,0.5,0]);
                    hold(app.TP,'on');
                    plot(app.TP,trace(1,:),'r');
                    if(numel(data) == 2)
                        region = data{2};
                        plot(app.TP,region' * max(sum(trace(:,region==1))),'b-','LineWidth',2);
                    end
                    hold(app.TP,'off');
                elseif label(i) == 'false' && pred(i) == 'true' && ~app.flag(2)
                    app.flag(2) = i;
                    data = readTimeTrace(files{i},1);
                    trace = data{1}(1:2,:);
                    app.scoreGaugeFP.Value = 100 * max(score(i,:));
                    plot(app.FP,trace(2,:),'Color',[0,0.5,0]);
                    hold(app.FP,'on');
                    plot(app.FP,trace(1,:),'r');
                    if(numel(data) == 2)
                        region = data{2};
                        plot(app.FP,region' * max(sum(trace(:,region==1))),'b-','LineWidth',2);
                    end
                    hold(app.FP,'off');
                elseif label(i) == 'true' && pred(i) == 'false' && ~app.flag(3)
                    app.flag(3) = i;
                    data = readTimeTrace(files{i},1);
                    trace = data{1}(1:2,:);
                    app.scoreGaugeFN.Value = 100 * max(score(i,:));
                    plot(app.FN,trace(2,:),'Color',[0,0.5,0]);
                    hold(app.FN,'on');
                    plot(app.FN,trace(1,:),'r');
                    if(numel(data) == 2)
                        region = data{2};
                        plot(app.FN,region' * max(sum(trace(:,region==1))),'b-','LineWidth',2);
                    end
                    hold(app.FN,'off');
                elseif label(i) == 'false' && pred(i) == 'false' && ~app.flag(4)
                    app.flag(4) = i;
                    data = readTimeTrace(files{i},1);
                    trace = data{1}(1:2,:);
                    app.scoreGaugeTN.Value = 100 * max(score(i,:));
                    plot(app.TN,trace(2,:),'Color',[0,0.5,0]);
                    hold(app.TN,'on');
                    plot(app.TN,trace(1,:),'r');
                    if(numel(data) == 2)
                        region = data{2};
                        plot(app.TN,region' * max(sum(trace(:,region==1))),'b-','LineWidth',2);
                    end
                    hold(app.TN,'off');
                end
                if all(app.flag>0)
                    break
                end
            end
        end

        % Button pushed function: TP1
        function TP1ButtonPushed(app, event)
            changeTrace(app,'TP',1);
        end

        % Button pushed function: TP0
        function TP0ButtonPushed(app, event)
            changeTrace(app,'TP',-1);
        end

        % Button pushed function: FP1
        function FP1ButtonPushed(app, event)
            changeTrace(app,'FP',1);
        end

        % Button pushed function: FP0
        function FP0ButtonPushed(app, event)
            changeTrace(app,'FP',-1);
        end

        % Button pushed function: FN1
        function FN1ButtonPushed(app, event)
            changeTrace(app,'FN',1);
        end

        % Button pushed function: FN0
        function FN0ButtonPushed(app, event)
            changeTrace(app,'FN',-1);
        end

        % Button pushed function: TN1
        function TN1ButtonPushed(app, event)
            changeTrace(app,'TN',1);
        end

        % Button pushed function: TN0
        function TN0ButtonPushed(app, event)
            changeTrace(app,'TN',-1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TP = uiaxes(app.UIFigure);
            title(app.TP, 'True Positive')
            xlabel(app.TP, 'Frame')
            ylabel(app.TP, 'Intensity')
            app.FP = uiaxes(app.UIFigure);
            title(app.FP, 'False Positive')
            xlabel(app.FP, 'Frame')
            ylabel(app.FP, 'Intensity')
            app.FN = uiaxes(app.UIFigure);
            title(app.FN, 'False Negative')
            xlabel(app.FN, 'Frame')
            ylabel(app.FN, 'Intensity')
            app.TN = uiaxes(app.UIFigure);
            title(app.TN, 'True Negative')
            xlabel(app.TN, 'Frame')
            ylabel(app.TN, 'Intensity')
            app.TP1 = uibutton(app.UIFigure, 'push');
            app.TP1.ButtonPushedFcn = createCallbackFcn(app, @TP1ButtonPushed, true);
            app.TP1.Text = '>';
            app.TP0 = uibutton(app.UIFigure, 'push');
            app.TP0.ButtonPushedFcn = createCallbackFcn(app, @TP0ButtonPushed, true);
            app.TP0.Text = '<';
            app.FP1 = uibutton(app.UIFigure, 'push');
            app.FP1.ButtonPushedFcn = createCallbackFcn(app, @FP1ButtonPushed, true);
            app.FP1.Text = '>';
            app.FP0 = uibutton(app.UIFigure, 'push');
            app.FP0.ButtonPushedFcn = createCallbackFcn(app, @FP0ButtonPushed, true);
            app.FP0.Text = '<';
            app.TN1 = uibutton(app.UIFigure, 'push');
            app.TN1.ButtonPushedFcn = createCallbackFcn(app, @TN1ButtonPushed, true);
            app.TN1.Text = '>';
            app.TN0 = uibutton(app.UIFigure, 'push');
            app.TN0.ButtonPushedFcn = createCallbackFcn(app, @TN0ButtonPushed, true);
            app.TN0.Text = '<';
            app.FN1 = uibutton(app.UIFigure, 'push');
            app.FN1.ButtonPushedFcn = createCallbackFcn(app, @FN1ButtonPushed, true);
            app.FN1.Text = '>';
            app.FN0 = uibutton(app.UIFigure, 'push');
            app.FN0.ButtonPushedFcn = createCallbackFcn(app, @FN0ButtonPushed, true);
            app.FN0.Text = '<';
            app.scoreLabel_3 = uilabel(app.UIFigure);
            app.scoreLabel_3.Text = 'score';
            app.scoreGaugeFN = uigauge(app.UIFigure, 'semicircular');
            app.scoreGaugeFN.Limits = [50 100];
            app.scoreGaugeFN.Value = 50;
            app.scoreLabel = uilabel(app.UIFigure);
            app.scoreLabel.Text = 'score';
            app.scoreGaugeTP = uigauge(app.UIFigure, 'semicircular');
            app.scoreGaugeTP.Limits = [50 100];
            app.scoreGaugeTP.Value = 50;
            app.scoreLabel_2 = uilabel(app.UIFigure);
            app.scoreLabel_2.Text = 'score';
            app.scoreGaugeFP = uigauge(app.UIFigure, 'semicircular');
            app.scoreGaugeFP.Limits = [50 100];
            app.scoreGaugeFP.Value = 50;
            app.scoreLabel_4 = uilabel(app.UIFigure);
            app.scoreLabel_4.Text = 'score';
            app.scoreGaugeTN = uigauge(app.UIFigure, 'semicircular');
            app.scoreGaugeTN.Limits = [50 100];
            app.scoreGaugeTN.Value = 50;
        end
    end

    methods (Access = public)

        % Construct app
        function app = showResult(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end