classdef toolFilePickerTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.Button
        TextAreaLabel  matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
    end


    methods (Access = public)
    
        function FilePickerReturned(app, event, callbackFcn, files)
            newEvent = struct();
            newEvent.Source = event.Source;
            newEvent.Files = files;
            callbackFcn(app, newEvent);
        end
        
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if event.Source == app.Button
                if isfield(event,'Files')
                    app.TextArea.Value = strcat(event.Files,',');
                else
                    toolFilePicker(app, event, @ButtonPushed);
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = toolFilePickerTest

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end