classdef toolLabelSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TracesFileSelectionUIFigure  matlab.ui.Figure
        Tree                         matlab.ui.container.Tree
        FolderNode                   matlab.ui.container.TreeNode
        Folder2Node                  matlab.ui.container.TreeNode
        File1Node                    matlab.ui.container.TreeNode
        File2Node                    matlab.ui.container.TreeNode
        TraceFormatDropDownLabel     matlab.ui.control.Label
        TraceFormatDropDown          matlab.ui.control.DropDown
        SelectButton                 matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        selectedFiles % Description
        parentApp % Description
    end

    methods (Access = private)
    
        function results = addNodes(app, rootNode, rootPath)
            
            if ~isvalid(rootNode)
                results = false;
                return
            end
            
            file = dir(rootPath);
            for i = 1 : length(file)
                [~,~,ext] = fileparts(file(i).name);
                if file(i).isdir && "."~=file(i).name(1) && "$"~=file(i).name(1)
                    t = uitreenode(rootNode,'Text',file(i).name,'Icon','Folder-icon.png');
                    t.UserData = fullfile(rootPath,file(i).name);
                elseif ~file(i).isdir && strcmp(ext,app.TraceFormatDropDown.Value)
                    t = uitreenode(rootNode,'Text',file(i).name);
                    t.UserData = fullfile(rootPath,file(i).name);
                    t.Icon = 'Trace-icon.png';
                end
            end
            drawnow;
            results = true;
            return
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            
            app.parentApp = parent;
            
            for t = app.Tree.Children'
                t.delete;
            end
            
            root = '~';
            addNodes(app, app.Tree, root);
            for t = app.Tree.Children'
                if isvalid(t)
                    if ~isempty(t.UserData) && isfolder(t.UserData)
                        addNodes(app, t, t.UserData);
                    end
                end
            end
        end

        % Node expanded function: Tree
        function TreeNodeExpanded(app, event)
            node = event.Node;
            for t = node.Children'   
                
                if ~isvalid(t)
                    continue
                elseif ~isempty(t.Children)
                    continue
                end
                
                if ~isempty(t.UserData) && isfolder(t.UserData)
                    addNodes(app, t, t.UserData);
                end
            end
            
        end

        % Close request function: TracesFileSelectionUIFigure
        function TracesFileSelectionUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Value changed function: TraceFormatDropDown
        function TraceFormatDropDownValueChanged(app, event)
            value = app.TraceFormatDropDown.Value;
            startupFcn(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            TracesFileSelectionUIFigureCloseRequest(app, event)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
        if ismethod(app.parentApp,'ChoosePathButtonPushed')
            app.parentApp.ChoosePathButtonPushed(app.Tree.SelectedNodes);
        end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TracesFileSelectionUIFigure = uifigure;
            app.TracesFileSelectionUIFigure.Name = 'Traces File Selection';
            app.TracesFileSelectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @TracesFileSelectionUIFigureCloseRequest, true);
            app.Tree = uitree(app.TracesFileSelectionUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.NodeExpandedFcn = createCallbackFcn(app, @TreeNodeExpanded, true);
            app.FolderNode = uitreenode(app.Tree);
            app.FolderNode.Icon = 'Folder-icon.png';
            app.FolderNode.Text = 'Folder';
            app.Folder2Node = uitreenode(app.FolderNode);
            app.Folder2Node.Icon = 'Folder-icon.png';
            app.Folder2Node.Text = 'Folder2';
            app.File1Node = uitreenode(app.Folder2Node);
            app.File1Node.Icon = 'Trace-icon.png';
            app.File1Node.Text = 'File1';
            app.File2Node = uitreenode(app.FolderNode);
            app.File2Node.Icon = 'Trace-icon.png';
            app.File2Node.Text = 'File2';
            app.TraceFormatDropDownLabel = uilabel(app.TracesFileSelectionUIFigure);
            app.TraceFormatDropDownLabel.Text = 'Trace Format';
            app.TraceFormatDropDown = uidropdown(app.TracesFileSelectionUIFigure);
            app.TraceFormatDropDown.Items = {'*.traces', '*.dat', '*.mat'};
            app.TraceFormatDropDown.ItemsData = {'.traces', '.dat', '.mat'};
            app.TraceFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @TraceFormatDropDownValueChanged, true);
            app.TraceFormatDropDown.Value = '.traces';
            app.SelectButton = uibutton(app.TracesFileSelectionUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.CancelButton = uibutton(app.TracesFileSelectionUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = toolLabelSetup(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TracesFileSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TracesFileSelectionUIFigure)
        end
    end
end